classdef character < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Button    matlab.ui.control.Button
        tip       matlab.ui.control.Label
        key_call  matlab.ui.control.EditField
        Label_4   matlab.ui.control.Label
        key_ask   matlab.ui.control.EditField
        Label_2   matlab.ui.control.Label
        key_sale  matlab.ui.control.EditField
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        parent; %存储主界面app 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.parent = parent;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %判断是否只输入一个字符
            if length(app.key_sale.Value) == 1 && length(app.key_ask.Value) == 1 && length(app.key_call.Value) == 1
                %判断输入字符是否重复
                if strcmp(app.key_sale.Value,app.key_ask.Value)||strcmp(app.key_ask.Value,app.key_call.Value)||strcmp(app.key_sale.Value,app.key_call.Value)
                    run errortip.mlapp; %若输入字符重复，错误提示窗口弹出
                else
                    %将输入的键盘控制字符数据传递到主界面app属性
                    keyboard_input(app.parent,app.key_sale.Value,app.key_ask.Value,app.key_call.Value);
                    %更新主界面键盘控制字符信息
                    app.parent.sale_character.Text = app.key_sale.Value;
                    app.parent.ask_character.Text = app.key_ask.Value;
                    app.parent.call_character.Text = app.key_call.Value;
                    app.parent.sale_character.FontColor = 'r';
                    app.parent.ask_character.FontColor  = 'r';
                    app.parent.call_character.FontColor = 'r';
                    delete(app);
                end
            else
                run errortip.mlapp; %若同框内输入多个字符，错误提示窗口弹出
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '键盘输入确定字符控制';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '处理现场售票：';
            app.key_sale = uieditfield(app.Panel, 'text');
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '处理现场咨询：';
            app.key_ask = uieditfield(app.Panel, 'text');
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '处理电话咨询：';
            app.key_call = uieditfield(app.Panel, 'text');
            app.tip = uilabel(app.Panel);
            app.tip.Text = '提示：请输入对应控制字符（一个），注意不要重复';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = character(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end