classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_2                   matlab.ui.container.Panel
        enter_people_num_text     matlab.ui.control.Label
        enter_people_num_label    matlab.ui.control.Label
        all_people_num_text       matlab.ui.control.Label
        all_people_num_label      matlab.ui.control.Label
        server_area_label         matlab.ui.control.Label
        gate_label                matlab.ui.control.Label
        call_label_button         matlab.ui.control.Button
        ask_label_button          matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        sale_label_button         matlab.ui.control.Button
        call_people_allnum_text   matlab.ui.control.Label
        call_people_allnum_label  matlab.ui.control.Label
        call_people_bk            matlab.ui.control.Label
        ask_people_allnum_text    matlab.ui.control.Label
        sale_people_allnum_text   matlab.ui.control.Label
        ask_people_allnum_label   matlab.ui.control.Label
        sale_people_allnum_label  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        help_button               matlab.ui.control.Button
        mouse_button              matlab.ui.container.Panel
        Lamp3                     matlab.ui.control.Lamp
        call_button               matlab.ui.control.Label
        ask_button                matlab.ui.control.Label
        sale_button               matlab.ui.control.Label
        call_label2               matlab.ui.control.Label
        ask_label2                matlab.ui.control.Label
        sale_label2               matlab.ui.control.Label
        call_time_rate            matlab.ui.control.NumericEditField
        Label_13                  matlab.ui.control.Label
        ask_time_rate             matlab.ui.control.NumericEditField
        Label_12                  matlab.ui.control.Label
        sale_time_rate            matlab.ui.control.NumericEditField
        Label_11                  matlab.ui.control.Label
        call_rate                 matlab.ui.control.NumericEditField
        Label_6                   matlab.ui.control.Label
        ask_rate                  matlab.ui.control.NumericEditField
        Label_5                   matlab.ui.control.Label
        sale_rate                 matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        start                     matlab.ui.control.Button
        keyboard_character        matlab.ui.container.Panel
        Lamp2                     matlab.ui.control.Lamp
        call_character            matlab.ui.control.Label
        ask_character             matlab.ui.control.Label
        sale_character            matlab.ui.control.Label
        call_label                matlab.ui.control.Label
        ask_label                 matlab.ui.control.Label
        sale_label                matlab.ui.control.Label
        selectGroup               matlab.ui.container.ButtonGroup
        mouse_select_button       matlab.ui.control.Button
        mouse_select              matlab.ui.control.RadioButton
        keyboard_select_button    matlab.ui.control.Button
        button_select             matlab.ui.control.RadioButton
        keyboard_select           matlab.ui.control.RadioButton
        system_select             matlab.ui.control.RadioButton
        cinema                    matlab.ui.control.Label
        Panel_3                   matlab.ui.container.Panel
        all_io_data_button        matlab.ui.control.Button
        percent_label             matlab.ui.control.Label
        server_occupy             matlab.ui.control.Label
        server_occupy_label       matlab.ui.control.Label
        sLabel_3                  matlab.ui.control.Label
        sLabel_2                  matlab.ui.control.Label
        sLabel                    matlab.ui.control.Label
        call_period               matlab.ui.control.Label
        ask_period                matlab.ui.control.Label
        sale_period               matlab.ui.control.Label
        call_period_label         matlab.ui.control.Label
        ask_period_label          matlab.ui.control.Label
        sale_period_label         matlab.ui.control.Label
        time_label                matlab.ui.control.Label
        time                      matlab.ui.control.Label
        endButton                 matlab.ui.control.Button
        sale_people_bk            matlab.ui.control.Label
        ask_people_bk             matlab.ui.control.Label
        draw_2                    matlab.ui.control.UIAxes
        draw                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        enter_people_num = 0; %此刻进入电影院的人数
        sale_people_num = 0;  %此刻进入电影院买票的人数
        ask_people_num = 0;   %此刻进入电影院咨询的人数
        call_people_num = 0;  %此刻电话咨询的人数
        all_people_num = 0;   %在电影院的总人数
        sale_people_allnum = 0; %等待买票的总人数
        ask_people_allnum = 0;  %等待现场咨询的总人数
        call_people_allnum = 0; %等待电话咨询的总人数
        process_sale_num = 0; %已经处理完售票的人数
        process_ask_num = 0; %已经处理完现场咨询的人数
        process_call_num = 0; %已经处理完电话咨询的人数
        people_dest=[]; %每次进来的人的目的地
        people_interval = 6; %进入口每个人的间隔
        stop_simulate = 0; %是否停止仿真
        server_laststate = 0; %服务员的上一状态
        server_state = 0; %服务员的现在状态
        datetimer2; %定时器2
        key_sale='a'; %键盘控制字符，'a'对应处理售票
        key_ask='s'; %键盘控制字符，'s'对应处理现场咨询
        key_call='d'; %键盘控制字符，'d'对应处理电话咨询
        server_sale_time = []; %售票处的每个人等待时间列表
        server_ask_time = [];  %现场咨询处的每个人等待时间列表
        server_call_time = []; %电话咨询处的每个人等待时间列表
        server_time = 0; %服务员的工作时间
        server_sale_pause_list = []; %服务员服务每个买票人的时间列表
        server_ask_pause_list =[]; %服务员服务每个现场咨询人的时间列表
        server_call_pause_list = []; %服务员服务每个电话咨询人的时间列表
        mouse_dir=struct('left',1,'middle',2,'right',3); %鼠标控制按键
        work_state = 0;
    end
    
    properties (Access = public)
       %模型历来仿真的输入输出参数列表
        input_output=[];
        subtable;
    end
    
    methods (Access = private)
        
        function draw_people(app,x,y) %画人
            people_size = 3 ;
            rectangle(app.draw,'Position',[x,y,people_size,people_size],'LineWidth',1,'EdgeColor','r','FaceColor','r');
            text(app.draw,x,y+people_size/2,'人','Color','white');
        end
        
        function draw_server(app,x,y) %画服务员
            server_length = 14;
            server_width  = 5;
            rectangle(app.draw_2,'Position',[x,y,server_width,server_length],'LineWidth',1,'EdgeColor','black','FaceColor','black');
            text(app.draw_2,x+1,y+server_length/2,{'服','务','员'},'FontSize',15,'Color','white');
        end

        function people_move(app,where) %人移动
            dest_x = 90;
            dest_y = [];
            %生成此刻进来人的初始y坐标
            original_y = (-app.ask_people_num)*app.people_interval:app.people_interval:(app.sale_people_num-1)*app.people_interval;
            original_y = fliplr(original_y);%翻转一下
            for i=1:length(where)
                %根据每个人的不同去向选择不同y坐标
                if where(i) == 0
                    dest_y = [dest_y 35];
                elseif where(i) == 1
                    dest_y = [dest_y 7];
                end
            end
            %运动更新到对应位置
            x = linspace(0,dest_x,25);
            if ~isempty(dest_y) %判断此时是否无人
                k = (dest_y-original_y)/dest_x;
                b = dest_y-k*dest_x;
                y = k'.*x+b';
                for i=1:length(x)
                    h = get(app.draw, 'children');
                    for j=1:app.enter_people_num
                        delete(h(2*j-1));
                        delete(h(2*j));
                    end
                    for j=1:app.enter_people_num
                        draw_people(app,x(i),y(j,i));
                    end                
                    pause(0.0001);
                end
            end
        end
        
        function timer_init(app) %定时器1初始化
            datetimer = timer;
            datetimer.StartDelay = 0;
            datetimer.Period = 1;
            datetimer.ExecutionMode = 'fixedRate';
            datetimer.BusyMode = 'queue';
            datetimer.TimerFcn = @(~,~) get_current_time(app);
            start(datetimer);
        end

        function timer2_init(app) %定时器2初始化
            app.datetimer2 = timer;
            app.datetimer2.StartDelay = 0;
            app.datetimer2.Period = 5; %设置单位时间为5s
            app.datetimer2.ExecutionMode = 'fixedRate';
            app.datetimer2.BusyMode = 'queue';
            app.datetimer2.TimerFcn = @(~,~) generate_people_num(app);
            start(app.datetimer2);
        end

        
        function get_current_time(app) %获取当前时间
            T = clock;
            set(app.time,'Text',[num2str(T(4)),':',num2str(T(5))]);
        end

        function generate_people_num(app) %产生顾客人数并移动至对应位置
            %生成三种人数，服从泊松分布
            app.sale_people_num = random('poisson',app.sale_rate.Value,1);
            app.ask_people_num = random('poisson',app.ask_rate.Value,1);
            app.call_people_num = random('poisson',app.call_rate.Value,1);
            %在影院的各类总人数计算
            app.enter_people_num = app.sale_people_num + app.ask_people_num;
            app.sale_people_allnum = app.sale_people_allnum + app.sale_people_num;
            app.ask_people_allnum = app.ask_people_allnum + app.ask_people_num;
            app.call_people_allnum = app.call_people_allnum + app.call_people_num;
            app.all_people_num = app.all_people_num + app.enter_people_num;
            %数量可视化
            app.all_people_num_text.Text = string(app.all_people_num);
            app.enter_people_num_text.Text = string(app.enter_people_num);
            %生成对应去向
            app.people_dest = zeros(1,app.sale_people_num);
            app.people_dest = [app.people_dest ones(1,app.ask_people_num)];
            for i=1:app.sale_people_num
                draw_people(app,0,(i-1)*app.people_interval);
            end
            for i=1:app.ask_people_num
                draw_people(app,0,-i*app.people_interval);
            end
            people_move(app,app.people_dest); %运动更新到相应位置
            app.sale_people_allnum_text.Text = string(app.sale_people_allnum);
            app.ask_people_allnum_text.Text = string(app.ask_people_allnum);
            app.call_people_allnum_text.Text = string(app.call_people_allnum);
            %电话咨询有人，指示灯亮红灯
            if app.call_people_allnum>0
                app.Lamp.Color = 'r';
            end
            %记录到达的人开始等待的时间
            nowtime = ones(1,app.sale_people_num)*toc;
            app.server_sale_time = [app.server_sale_time nowtime];
            nowtime = ones(1,app.ask_people_num)*toc;
            app.server_ask_time = [app.server_ask_time nowtime];
            nowtime = ones(1,app.call_people_num)*toc;
            app.server_call_time = [app.server_call_time nowtime];
        end

        
        function server_process(app) %服务员处理函数
            switch app.server_state
                case 1
                    app.process_sale_num = app.process_sale_num + 1;
                    app.server_sale_time(app.process_sale_num) =  toc - app.server_sale_time(app.process_sale_num) ; %获取等待时间
                    pausetime = exprnd(app.sale_time_rate.Value); %负指数分布处理时间
                    pause(pausetime);
                    app.sale_people_allnum = app.sale_people_allnum - 1; %处理完人数减一
                    app.sale_people_allnum_text.Text = string(app.sale_people_allnum); %更新可视化数量
                    app.server_sale_pause_list = [app.server_sale_pause_list pausetime]; %处理时间列表
                case 2
                    app.process_ask_num = app.process_ask_num + 1;
                    app.server_ask_time(app.process_ask_num) = toc - app.server_ask_time(app.process_ask_num); %获取等待时间
                    pausetime = exprnd(app.ask_time_rate.Value); %负指数分布处理时间
                    pause(pausetime);
                    app.ask_people_allnum = app.ask_people_allnum - 1; %处理完人数减一
                    app.ask_people_allnum_text.Text = string(app.ask_people_allnum); %更新可视化数量     
                    app.server_ask_pause_list = [app.server_ask_pause_list pausetime]; %处理时间列表
                case 3
                    app.process_call_num = app.process_call_num + 1;
                    app.server_call_time(app.process_call_num) = toc - app.server_call_time(app.process_call_num); %获取等待时间
                    pausetime = exprnd(app.call_time_rate.Value); %负指数分布处理时间
                    pause(pausetime);
                    app.call_people_allnum = app.call_people_allnum - 1; %处理完人数减一
                    app.call_people_allnum_text.Text = string(app.call_people_allnum); %更新可视化数量
                    app.server_call_pause_list = [app.server_call_pause_list pausetime]; %处理时间列表
            end
            if app.call_people_allnum == 0
                app.Lamp.Color = 'g'; %无人电话，则绿灯亮
            end            
        end
        
        function initialize(app)
            app.enter_people_num = 0; %此刻进入电影院的人数
            app.sale_people_num = 0;  %此刻进入电影院买票的人数
            app.ask_people_num = 0;   %此刻进入电影院咨询的人数
            app.call_people_num = 0;  %此刻电话咨询的人数
            app.all_people_num = 0;   %在电影院的总人数
            app.sale_people_allnum = 0; %等待买票的总人数
            app.ask_people_allnum = 0;  %等待现场咨询的总人数
            app.call_people_allnum = 0; %等待电话咨询的总人数
            app.process_sale_num = 0; %已经处理完售票的人数
            app.process_ask_num = 0; %已经处理完现场咨询的人数
            app.process_call_num = 0; %已经处理完电话咨询的人数
            app.people_dest=[]; %每次进来的人的目的地
            app.people_interval = 6; %进入口每个人的间隔
            app.stop_simulate = 0; %是否停止仿真
            app.server_laststate = 0; %服务员的上一状态
            app.server_state = 0; %服务的现在状态
            app.server_sale_time = []; %售票处的每个人等待时间列表
            app.server_ask_time = [];  %现场咨询处的每个人等待时间列表
            app.server_call_time = []; %电话咨询处的每个人等待时间列表
            app.server_time = 0; %服务员的工作时间
            app.server_sale_pause_list = []; %服务员服务每个买票人的时间列表
            app.server_ask_pause_list =[]; %服务员服务每个现场咨询人的时间列表
            app.server_call_pause_list = []; %服务员服务每个电话咨询人的时间列表
            %服务员回到原点
            h = get(app.draw_2, 'children');
            delete(h);
            draw_server(app,30,-7);
            %输出清零
            app.sale_period.Text = '';
            app.ask_period.Text = '';
            app.call_period.Text = '';
            app.server_occupy.Text ='';
        end
        
        function not_system_process(app,server_x,server_y,dest_x,sale_y,ask_y,call_y,pausetime,update_rate)
            while(1)
                pause(0.000001); %暂停极小一段时间留以程序反应，使得能够终止仿真
                if app.stop_simulate == 1
                    stop(app.datetimer2); %暂停定时器2
                    delete(app.datetimer2); %删除定时器2
                    app.work_state = 0;
                    break
                end
                if (app.sale_people_allnum + app.ask_people_allnum) > 0 %如果影院里仍然有人的话
                    %根据选择的状态是否有人，来进行后续操作
                    if (app.server_state == 1 && app.sale_people_allnum>0) || (app.server_state == 2 && app.ask_people_allnum>0)
                        switch app.server_state
                            case 1
                                dest_y = sale_y;
                            case 2
                                dest_y = ask_y ;
                        end
                        if app.server_laststate == 0 %服务员上一状态在初始位置
                            %运动更新到对应位置
                            x = linspace(server_x,dest_x,update_rate);
                            k = (dest_y-server_y)/(dest_x-server_x);
                            b = dest_y- k * dest_x;
                            y = k*x+b;
                            for i=1:length(x)
                                h = get(app.draw_2, 'children');
                                delete(h);
                                draw_server(app,x(i),y(i));
                                pause(pausetime);
                            end
                        elseif app.server_laststate ~= app.server_state %服务员的位置发生变化
                            %判断服务员的上一状态位置
                            switch app.server_laststate
                                case 1
                                    source_y = sale_y;
                                case 2
                                    source_y = ask_y;
                                case 3
                                    source_y = call_y;
                            end
                            %运动更新到对应位置
                            y = linspace(source_y,dest_y,update_rate);
                            for i=1:length(y)
                                h = get(app.draw_2,'children');
                                delete(h);
                                draw_server(app,dest_x,y(i));
                                pause(pausetime);
                            end                               
                        end
                        app.server_laststate = app.server_state;
                        server_process(app);                           
                    end
                else
                    if app.call_people_allnum > 0
                        if app.server_state == 3
                            dest_y = call_y;
                            if app.server_laststate == 0 %服务员上一状态在初始位置
                                %运动更新到对应位置
                                x = linspace(server_x,dest_x,update_rate);
                                k = (dest_y-server_y)/(dest_x-server_x);
                                b = dest_y- k * dest_x;
                                y = k*x+b;
                                for i=1:length(x)
                                    h = get(app.draw_2, 'children');
                                    delete(h);
                                    draw_server(app,x(i),y(i));
                                    pause(pausetime);
                                end
                            elseif app.server_laststate == 1 %服务员上一状态在现场售票处
                                %运动更新到对应位置
                                y = linspace(sale_y,call_y,update_rate);
                                for i=1:length(y)
                                    h = get(app.draw_2, 'children');
                                    delete(h);
                                    draw_server(app,dest_x,y(i));
                                    pause(pausetime);
                                end
                            elseif app.server_laststate == 2 %服务员上一状态在现场咨询处
                                %运动更新到对应位置
                                y = linspace(ask_y,call_y,update_rate);
                                for i=1:length(y)
                                    h = get(app.draw_2, 'children');
                                    delete(h);                                   
                                    draw_server(app,dest_x,y(i));
                                    pause(pausetime);
                                end
                            end
                            app.server_laststate = app.server_state;
                            server_process(app);                               
                        end
                    else
                        %如果都没有人的话服务员回到初始位置
                        if app.server_state ~=0
                            app.server_state =0;
                            app.server_laststate = app.server_state;
                            %运动更新到对应位置
                            x = linspace(dest_x,server_x,update_rate);
                            k = (dest_y - server_y)/(dest_x - server_x);
                            b = dest_y - k * dest_x;
                            y = k * x + b;
                            for i=1:length(x)
                                h = get(app.draw_2, 'children');
                                delete(h);                               
                                draw_server(app,x(i),y(i));
                                pause(pausetime);
                            end
                        end                            
                    end
                end    
            end            
        end  
    end

    
    methods (Access = public)
        
        function keyboard_input(app,key_sale,key_ask,key_call) %将输入的键盘控制字符数据传递到主界面app属性
            app.key_sale = key_sale;
            app.key_ask  = key_ask;
            app.key_call = key_call;
        end
        
        function mouse_input(app,subfigure) %将选择的鼠标控制按键数据传递到主界面app属性
            app.mouse_dir=struct(subfigure.sale.Value,1,subfigure.ask.Value,2,subfigure.call.Value,3);  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '电影院售票与咨询系统';
            stop(timerfind); %暂停所有计时器
            delete(timerfind); %删除所有计时器
            timer_init(app); %开启定时器1
            draw_server(app,30,-7); %画出服务员初始位置
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            if app.work_state
                 run errortip3.mlapp;
            elseif app.sale_rate.Value<0||app.sale_rate.Value>5||...
                    app.ask_rate.Value<0||app.ask_rate.Value>5||...
                    app.call_rate.Value<0||app.call_rate.Value>5||...
                    app.sale_time_rate.Value<0.2||app.ask_time_rate.Value<0.2||app.call_time_rate.Value<0.2
                run errortip4.mlapp;
            else
                app.stop_simulate = 0;
                app.work_state = 1;
                initialize(app);
                server_x = 30; %服务员的初始位置
                server_y = -7;
                sale_y   = 29; %售票处位置
                ask_y    =  0; %现场咨询处位置
                call_y   =-29; %电话咨询处位置
                dest_x   =  7; %服务员处理位置的横坐标
                pausetime = 0.0001; %人和服务员运动更新暂停时间
                update_rate = 10; %决定人和服务员的运动速度
                tic %开始计时
                timer2_init(app); %开启定时器2，进行仿真
                if app.system_select.Value %服务员系统自动产生服务
                    while(1)
                        pause(0.000001); %暂停极小一段时间留以程序反应，使得能够终止仿真
                        if app.stop_simulate == 1 
                            stop(app.datetimer2); %暂停定时器2
                            delete(app.datetimer2); %删除定时器2
                            app.work_state = 0;
                            break
                        end
                        if (app.sale_people_allnum + app.ask_people_allnum) > 0 %如果影院里仍然有人的话
                            app.server_laststate = app.server_state;
                            if app.sale_people_allnum == 0 %售票处没人
                                dest_y = ask_y;
                                app.server_state = 2; %去现场咨询处
                            elseif app.ask_people_allnum == 0 %现场咨询处没人
                                dest_y = sale_y;
                                app.server_state = 1; %去现场售票处
                            else
                                %二者随机选择
                                if rand(1) > 0.5
                                    dest_y = sale_y;
                                    app.server_state = 1; %在现场售票处
                                else
                                    dest_y = ask_y;
                                    app.server_state = 2; %在现场咨询处
                                end                            
                            end
                            if app.server_laststate == 0 %服务员上一状态在初始位置
                                %运动更新到对应位置
                                x = linspace(server_x,dest_x,update_rate);
                                k = (dest_y-server_y)/(dest_x-server_x);
                                b = dest_y- k * dest_x;
                                y = k*x+b;
                                for i=1:length(x)
                                    h = get(app.draw_2, 'children');
                                    delete(h);
                                    draw_server(app,x(i),y(i));
                                    pause(pausetime);
                                end
                            elseif app.server_laststate ~= app.server_state %服务员的位置发生变化
                                %判断服务员的上一状态位置
                                switch app.server_laststate
                                    case 1
                                        source_y = sale_y; 
                                    case 2
                                        source_y = ask_y;
                                    case 3
                                        source_y = call_y;
                                end
                                %运动更新到对应位置
                                y = linspace(source_y,dest_y,update_rate);
                                for i=1:length(y)
                                    h = get(app.draw_2,'children');
                                    delete(h);
                                    draw_server(app,dest_x,y(i));
                                    pause(pausetime);
                                end
                            end
                            server_process(app);
                        else
                            if app.call_people_allnum > 0 %电话咨询处有人等待
                                app.server_laststate = app.server_state;
                                app.server_state = 3;
                                dest_y = call_y;
                                switch app.server_laststate
                                    case 0 %服务员上一状态在初始位置
                                        %运动更新到对应位置
                                        x = linspace(server_x,dest_x,update_rate);
                                        k = (dest_y-server_y)/(dest_x-server_x);
                                        b = dest_y- k * dest_x;
                                        y = k*x+b;
                                        for i=1:length(x)
                                            h = get(app.draw_2, 'children');
                                            delete(h);
                                            draw_server(app,x(i),y(i));
                                            pause(pausetime);
                                        end                                    
                                    case 1 %服务员上一状态在现场售票处
                                        %运动更新到对应位置
                                        y = linspace(sale_y,call_y,update_rate);
                                        for i=1:length(y)
                                            h = get(app.draw_2, 'children');
                                            delete(h);
                                            draw_server(app,dest_x,y(i));
                                            pause(pausetime);
                                        end                                   
                                    case 2 %服务员上一状态在现场咨询处
                                        %运动更新到对应位置
                                        y = linspace(ask_y,call_y,update_rate);
                                        for i=1:length(y)
                                            h = get(app.draw_2, 'children');
                                            delete(h);                                   
                                            draw_server(app,dest_x,y(i));
                                            pause(pausetime);
                                        end
                                end
                                server_process(app);
                            else
                                %如果都没有人的话服务员回到初始位置
                                if app.server_state ~=0 
                                    app.server_state =0;
                                    %运动更新到对应位置
                                    x = linspace(dest_x,server_x,update_rate);
                                    k = (dest_y - server_y)/(dest_x - server_x);
                                    b = dest_y - k * dest_x;
                                    y = k * x + b;
                                    for i=1:length(x)
                                        h = get(app.draw_2, 'children');
                                        delete(h);                               
                                        draw_server(app,x(i),y(i));
                                        pause(pausetime);
                                    end
                                end                            
                            end
                        end
                    end
                else
                    %非系统自动产生服务处理函数
                    not_system_process(app,server_x,server_y,dest_x,sale_y,ask_y,call_y,pausetime,update_rate);           
                end
            end
        end

        % Button pushed function: endButton
        function endButtonPushed(app, event)
            if ~app.work_state
                run errortip5.mlapp;
            else
                app.server_time = toc; %记录服务员的总工作时间
                app.stop_simulate = 1 ; %停止仿真
                rate = (sum(app.server_sale_pause_list)+sum(app.server_ask_pause_list)+sum(app.server_call_pause_list))/app.server_time; %计算服务员工作时间的占用率
                app.server_occupy.Text = string(rate*100); 
                %计算各类服务的平均等待时间
                if app.process_sale_num
                    app.server_sale_time = sum(app.server_sale_time(1:app.process_sale_num))/app.process_sale_num;
                end
                if app.process_ask_num
                    app.server_ask_time = sum(app.server_ask_time(1:app.process_ask_num))/app.process_ask_num;
                end
                if app.process_call_num
                    app.server_call_time = sum(app.server_call_time(1:app.process_call_num))/app.process_call_num;
                end
                app.sale_period.Text = string(app.server_sale_time);
                app.ask_period.Text = string(app.server_ask_time);
                app.call_period.Text = string(app.server_call_time);
                if app.system_select.Value
                    server_mode ='系统选择';
                elseif app.keyboard_select.Value
                    server_mode = '键盘选择';
                elseif app.button_select.Value
                    server_mode = '按钮选择';
                elseif app.mouse_select.Value
                    server_mode = '鼠标选择';
                end            
                app.input_output = [app.input_output;{app.sale_rate.Value,app.ask_rate.Value,app.call_rate.Value,...
                    app.sale_time_rate.Value,app.ask_time_rate.Value,app.call_time_rate.Value,...
                    server_mode,app.server_sale_time,app.server_ask_time,app.server_call_time,rate*100}];  
            end          
        end

        % Button pushed function: sale_label_button
        function sale_label_buttonButtonPushed(app, event)
            if app.button_select.Value && app.sale_people_allnum > 0  
                app.server_state = 1;
            end
        end

        % Button pushed function: ask_label_button
        function ask_label_buttonButtonPushed(app, event)
            if app.button_select.Value && app.ask_people_allnum > 0
                app.server_state = 2;
            end            
        end

        % Button pushed function: call_label_button
        function call_label_buttonButtonPushed(app, event)
            if app.button_select.Value && app.call_people_allnum > 0 && ((app.sale_people_allnum + app.ask_people_allnum) == 0)
                app.server_state = 3;
            end            
        end

        % Selection changed function: selectGroup
        function selectGroupSelectionChanged(app, event)
            if app.keyboard_select.Value
                app.Lamp2.Color = 'r';
                app.Lamp3.Color = 'g';
            elseif app.mouse_select.Value
                app.Lamp3.Color = 'r';
                app.Lamp2.Color = 'g';
            else
                app.Lamp2.Color = 'g';
                app.Lamp3.Color = 'g';
            end
        end

        % Button pushed function: keyboard_select_button
        function keyboard_select_buttonButtonPushed(app, event)
            character(app); %打开键盘控制字符输入界面
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;  %储存按键状态
            if app.keyboard_select.Value %如果选中'键盘选择'
                switch key
                    case app.key_sale
                        if app.sale_people_allnum > 0 
                            %如果售票处等待人数大于0，则改变服务员状态为售票服务
                            app.server_state = 1; 
                        end
                    case app.key_ask
                        if app.ask_people_allnum > 0 
                            %如果现场咨询处等待人数大于0，则改变服务员状态为现场咨询服务
                            app.server_state = 2; 
                        end
                    case app.key_call
                        if app.call_people_allnum > 0 && ((app.sale_people_allnum + app.ask_people_allnum) == 0) 
                             %如果电话咨询处等待人数大于0并且售票处和现场咨询处均无人，则改变服务员状态为电话咨询服务
                            app.server_state = 3;
                        end
                end
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %选中'鼠标选择'并且鼠标点击在灰黑色区域内
            if app.mouse_select.Value && app.UIFigure.CurrentPoint(1)>210 &&...
                    app.UIFigure.CurrentPoint(1)<910 && app.UIFigure.CurrentPoint(2)>5 &&...
                    app.UIFigure.CurrentPoint(2)<770
                origin_s = app.server_state; %保存原来的状态
                switch app.UIFigure.SelectionType
                    case 'normal' %左键
                        app.server_state = app.mouse_dir.left;
                    case 'alt'    %右键
                        app.server_state = app.mouse_dir.right;
                    case 'extend' %滚轮
                        app.server_state = app.mouse_dir.middle;
                end
                switch app.server_state
                    case 1
                        if ~app.sale_people_allnum 
                            %如果鼠标点击售票按键，但是售票无人等待，则继续保持原状态
                            app.server_state = origin_s;
                        end
                    case 2
                        if ~app.ask_people_allnum 
                            %如果鼠标点击现场咨询按键，但是现场咨询无人等待，则继续保持原状态
                            app.server_state = origin_s;
                        end
                    case 3
                        if (app.sale_people_allnum + app.ask_people_allnum) > 0 || app.call_people_allnum == 0
                            %如果鼠标点击电话咨询按键吗，但是售票或现场咨询有人或者电话咨询无人，则继续保持原状态
                            app.server_state = origin_s;
                        end
                end
            end
        end

        % Button pushed function: mouse_select_button
        function mouse_select_buttonButtonPushed(app, event)
            mouse(app); %打开鼠标按键选择界面
        end

        % Button pushed function: help_button
        function help_buttonButtonPushed(app, event)
            run help.mlapp; %打开使用帮助界面
        end

        % Button pushed function: all_io_data_button
        function all_io_data_buttonButtonPushed(app, event)
            app.subtable = all_io_data();
            app.subtable.UITable.Data = [app.subtable.UITable.Data;app.input_output];          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.draw = uiaxes(app.UIFigure);
            app.draw.XLim = [0 100];
            app.draw.YLim = [-50 50];
            app.draw.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.draw.XTickLabel = '';
            app.draw.YTickLabel = '';
            app.draw_2 = uiaxes(app.UIFigure);
            app.draw_2.XLim = [0 40];
            app.draw_2.YLim = [-50 50];
            app.draw_2.XTickLabel = '';
            app.draw_2.YTickLabel = '';
            app.ask_people_bk = uilabel(app.UIFigure);
            app.ask_people_bk.Text = '';
            app.sale_people_bk = uilabel(app.UIFigure);
            app.sale_people_bk.Text = '';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = '        模型输出';
            app.endButton = uibutton(app.Panel_3, 'push');
            app.endButton.ButtonPushedFcn = createCallbackFcn(app, @endButtonPushed, true);
            app.endButton.Text = '仿真结束确认';
            app.time = uilabel(app.Panel_3);
            app.time.Text = '0';
            app.time_label = uilabel(app.Panel_3);
            app.time_label.Text = '实时时间：';
            app.sale_period_label = uilabel(app.Panel_3);
            app.sale_period_label.Text = '现场售票服务平均等待时间';
            app.ask_period_label = uilabel(app.Panel_3);
            app.ask_period_label.Text = '现场咨询服务平均等待时间';
            app.call_period_label = uilabel(app.Panel_3);
            app.call_period_label.Text = '电话咨询服务平均等待时间';
            app.sale_period = uilabel(app.Panel_3);
            app.sale_period.Text = '';
            app.ask_period = uilabel(app.Panel_3);
            app.ask_period.Text = '';
            app.call_period = uilabel(app.Panel_3);
            app.call_period.Text = '';
            app.sLabel = uilabel(app.Panel_3);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.Panel_3);
            app.sLabel_2.Text = 's';
            app.sLabel_3 = uilabel(app.Panel_3);
            app.sLabel_3.Text = 's';
            app.server_occupy_label = uilabel(app.Panel_3);
            app.server_occupy_label.Text = '服务员在工作时间的占用率';
            app.server_occupy = uilabel(app.Panel_3);
            app.server_occupy.Text = '';
            app.percent_label = uilabel(app.Panel_3);
            app.percent_label.Text = '%';
            app.all_io_data_button = uibutton(app.Panel_3, 'push');
            app.all_io_data_button.ButtonPushedFcn = createCallbackFcn(app, @all_io_data_buttonButtonPushed, true);
            app.all_io_data_button.Text = {'模型总输入输出'; '参数表格'};
            app.cinema = uilabel(app.UIFigure);
            app.cinema.Text = '电影院';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '   初始条件设置';
            app.selectGroup = uibuttongroup(app.Panel);
            app.selectGroup.SelectionChangedFcn = createCallbackFcn(app, @selectGroupSelectionChanged, true);
            app.selectGroup.Title = '服务员服务方式的选择';
            app.system_select = uiradiobutton(app.selectGroup);
            app.system_select.Text = '系统选择';
            app.system_select.Value = true;
            app.keyboard_select = uiradiobutton(app.selectGroup);
            app.keyboard_select.Text = '键盘选择';
            app.button_select = uiradiobutton(app.selectGroup);
            app.button_select.Text = '按钮选择';
            app.keyboard_select_button = uibutton(app.selectGroup, 'push');
            app.keyboard_select_button.ButtonPushedFcn = createCallbackFcn(app, @keyboard_select_buttonButtonPushed, true);
            app.keyboard_select_button.Text = '输入控制字符';
            app.mouse_select = uiradiobutton(app.selectGroup);
            app.mouse_select.Text = '鼠标选择';
            app.mouse_select_button = uibutton(app.selectGroup, 'push');
            app.mouse_select_button.ButtonPushedFcn = createCallbackFcn(app, @mouse_select_buttonButtonPushed, true);
            app.mouse_select_button.Text = '输入控制按键';
            app.keyboard_character = uipanel(app.Panel);
            app.keyboard_character.Title = '键盘选择对应字符';
            app.sale_label = uilabel(app.keyboard_character);
            app.sale_label.Text = '现场售票：';
            app.ask_label = uilabel(app.keyboard_character);
            app.ask_label.Text = '现场咨询：';
            app.call_label = uilabel(app.keyboard_character);
            app.call_label.Text = '电话咨询：';
            app.sale_character = uilabel(app.keyboard_character);
            app.sale_character.Text = 'a';
            app.ask_character = uilabel(app.keyboard_character);
            app.ask_character.Text = 's';
            app.call_character = uilabel(app.keyboard_character);
            app.call_character.Text = 'd';
            app.Lamp2 = uilamp(app.keyboard_character);
            app.start = uibutton(app.Panel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '确认仿真开始';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '售票人员到达率';
            app.sale_rate = uieditfield(app.Panel, 'numeric');
            app.sale_rate.Value = 0.5;
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '咨询人员到达率';
            app.ask_rate = uieditfield(app.Panel, 'numeric');
            app.ask_rate.Value = 0.8;
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '电话人员到达率';
            app.call_rate = uieditfield(app.Panel, 'numeric');
            app.call_rate.Value = 1;
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Text = '现场售票服务时长参数';
            app.sale_time_rate = uieditfield(app.Panel, 'numeric');
            app.sale_time_rate.Value = 1;
            app.Label_12 = uilabel(app.Panel);
            app.Label_12.Text = '现场咨询服务时长参数';
            app.ask_time_rate = uieditfield(app.Panel, 'numeric');
            app.ask_time_rate.Value = 1;
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = '电话咨询服务时长参数';
            app.call_time_rate = uieditfield(app.Panel, 'numeric');
            app.call_time_rate.Value = 1;
            app.mouse_button = uipanel(app.Panel);
            app.mouse_button.Title = '鼠标选择对应按键';
            app.sale_label2 = uilabel(app.mouse_button);
            app.sale_label2.Text = '现场售票：';
            app.ask_label2 = uilabel(app.mouse_button);
            app.ask_label2.Text = '现场咨询：';
            app.call_label2 = uilabel(app.mouse_button);
            app.call_label2.Text = '电话咨询：';
            app.sale_button = uilabel(app.mouse_button);
            app.sale_button.Text = 'left';
            app.ask_button = uilabel(app.mouse_button);
            app.ask_button.Text = 'middle';
            app.call_button = uilabel(app.mouse_button);
            app.call_button.Text = 'right';
            app.Lamp3 = uilamp(app.mouse_button);
            app.help_button = uibutton(app.Panel, 'push');
            app.help_button.ButtonPushedFcn = createCallbackFcn(app, @help_buttonButtonPushed, true);
            app.help_button.Text = '使用帮助';
            app.sale_people_allnum_label = uilabel(app.UIFigure);
            app.sale_people_allnum_label.Text = '现场买票总人数：';
            app.ask_people_allnum_label = uilabel(app.UIFigure);
            app.ask_people_allnum_label.Text = '现场咨询总人数：';
            app.sale_people_allnum_text = uilabel(app.UIFigure);
            app.sale_people_allnum_text.Text = '0';
            app.ask_people_allnum_text = uilabel(app.UIFigure);
            app.ask_people_allnum_text.Text = '0';
            app.call_people_bk = uilabel(app.UIFigure);
            app.call_people_bk.Text = '';
            app.call_people_allnum_label = uilabel(app.UIFigure);
            app.call_people_allnum_label.Text = '电话咨询总人数：';
            app.call_people_allnum_text = uilabel(app.UIFigure);
            app.call_people_allnum_text.Text = '0';
            app.sale_label_button = uibutton(app.UIFigure, 'push');
            app.sale_label_button.ButtonPushedFcn = createCallbackFcn(app, @sale_label_buttonButtonPushed, true);
            app.sale_label_button.Text = {'现'; '场'; '售'; '票'; '处'};
            app.Lamp = uilamp(app.UIFigure);
            app.ask_label_button = uibutton(app.UIFigure, 'push');
            app.ask_label_button.ButtonPushedFcn = createCallbackFcn(app, @ask_label_buttonButtonPushed, true);
            app.ask_label_button.Text = {'现'; '场'; '咨'; '询'; '处'};
            app.call_label_button = uibutton(app.UIFigure, 'push');
            app.call_label_button.ButtonPushedFcn = createCallbackFcn(app, @call_label_buttonButtonPushed, true);
            app.call_label_button.Text = {'电'; '话'; '咨'; '询'; '处'};
            app.gate_label = uilabel(app.UIFigure);
            app.gate_label.Text = {'入'; '口'};
            app.server_area_label = uilabel(app.UIFigure);
            app.server_area_label.Text = '  服务区域';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '        模型状态';
            app.all_people_num_label = uilabel(app.Panel_2);
            app.all_people_num_label.Text = '影院总人数：';
            app.all_people_num_text = uilabel(app.Panel_2);
            app.all_people_num_text.Text = '0';
            app.enter_people_num_label = uilabel(app.Panel_2);
            app.enter_people_num_label.Text = '此刻进影院人数：';
            app.enter_people_num_text = uilabel(app.Panel_2);
            app.enter_people_num_text.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end