classdef mouse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Button    matlab.ui.control.Button
        call      matlab.ui.control.DropDown
        Label_3   matlab.ui.control.Label
        ask       matlab.ui.control.DropDown
        Label_2   matlab.ui.control.Label
        sale      matlab.ui.control.DropDown
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        parent % 存储主界面app
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent)
            app.parent = parent;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %判断是否选择重复
            if strcmp(app.sale.Value,app.ask.Value)||strcmp(app.sale.Value,app.call.Value)||strcmp(app.ask.Value,app.call.Value)
                run errortip2.mlapp; %如果选择重复则错误提示窗口弹出
            else
                %更新主界面对应鼠标控制按键信息
                app.parent.sale_button.Text = app.sale.Value; 
                app.parent.ask_button.Text = app.ask.Value;
                app.parent.call_button.Text = app.call.Value;
                app.parent.sale_button.FontColor = 'r';
                app.parent.ask_button.FontColor = 'r';
                app.parent.call_button.FontColor = 'r';
                mouse_input(app.parent,app); %将选择的鼠标控制按键数据传递到主界面app属性
                delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '鼠标按键控制选择';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '现场售票';
            app.sale = uidropdown(app.Panel);
            app.sale.Items = {'left', 'middle', 'right'};
            app.sale.Value = 'left';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '现场咨询';
            app.ask = uidropdown(app.Panel);
            app.ask.Items = {'left', 'middle', 'right'};
            app.ask.Value = 'middle';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '电话咨询';
            app.call = uidropdown(app.Panel);
            app.call.Items = {'left', 'middle', 'right'};
            app.call.Value = 'right';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mouse(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end